classdef IAD_acquire < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SaturatedLamp             matlab.ui.control.Lamp
        SaturatedLampLabel        matlab.ui.control.Label
        DarkLamp                  matlab.ui.control.Lamp
        ConnectedLamp             matlab.ui.control.Lamp
        ConnectButton             matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        OutputNameEditField       matlab.ui.control.EditField
        OutputNameEditFieldLabel  matlab.ui.control.Label
        AcquireDataButton         matlab.ui.control.Button
        AutoexposeButton          matlab.ui.control.Button
        AcquireDarkButton         matlab.ui.control.Button
        InttimeEditField          matlab.ui.control.NumericEditField
        IntegrationTimemsEditFieldLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        ModelNo = "BTC621E-1024"    % App only valid for this spectrometer
        NPixels = 1024              % How many pixels this spectrometer has
        OldPath = ""

        % Calibration coefficients (spectrometer EEPROM)
        Coefs = struct(...
            'a0',3.20234E+2,'a1',8.47811E-1,'a2',-1.03570E-4,'a3',7.79542E-10,...
            'b0',-3.67543E+2,'b1',1.11888E+0,'b2',5.91434E-5,'b3',8.96565E-8);

        % coefs = struct( ...
        %     'a0',3.20180e+02,'a1',8.50798e-1, ...
        %     'a2',-1.09490e-4,'a3',6.30802e-09 ...
        %     );                      % Calibration coefficients (CAC 230619)

        DataPtr     % Pointer to memory location for collected data

        LampOn = [0, 1, 0];         % Color to indicate ready status
        LampOff = [0.6, 0.6, 0.6];  % Color to indicate non-ready status

    end
    
    properties (Access = public)

        DarkData (:,1) double
        RawData (:,1) double

        Initialized (1,1) logical = false % Are we connected?
        HasDark (1,1) logical = false
        Px
        WvArray

    end
    
    methods (Access = private)
        
        function PlotData(app)

            if app.HasDark
                pdata = double(flipud(app.DataPtr.Value))-app.DarkData;
                plot(app.UIAxes, app.WvArray, pdata, 'g')
                title(app.UIAxes, 'Dark-Corrected Spectrum')
            else
                pdata = flipud(app.DataPtr.Value);
                plot(app.UIAxes, app.WvArray, pdata)
                title(app.UIAxes, 'Raw Spectrum')
            end
            app.UIAxes.YAxis.Exponent = 0;
            if max(pdata) >= 65500
                app.SaturatedLamp.Color = app.LampOn;
            else
                app.SaturatedLamp.Color = app.LampOff;
            end
            
            
        end

        function PlotDarkData(app)
            plot(app.UIAxes, app.WvArray, flipud(app.DataPtr.Value), ...
                "Color", [0.4, 0.4, 0.4])
            app.UIAxes.YAxis.Exponent = 0;
            title(app.UIAxes, 'Dark Spectrum')
        end
        
        function EnableAll(app,state)

            [ ...
            app.InttimeEditField.Enable, ...
            app.AutoexposeButton.Enable, ...
            app.AcquireDarkButton.Enable, ...
            app.AcquireDataButton.Enable, ...
            app.OutputNameEditField.Enable, ...
            app.SaveButton.Enable ...
            ] = deal(state);
            
        end

        
        function d = WaitForSpec(app)

            d = uiprogressdlg(app.UIFigure, "Message", "Acquiring", ...
                "Title", "Please Wait", "Value", 1);
         
        end
    end
    
    methods (Access = public)
        
        function results = Acquire(app)
    
            d = app.WaitForSpec;

            app.SetIntTime
            calllib('bwtekusb','bwtekDataReadUSB', ...
                0,app.DataPtr,0);
            results = flipud(app.DataPtr.Value);

            close(d)
            
        end
        
        function SetIntTime(app, inttime)           

            if nargin < 2
                inttime = app.InttimeEditField.Value;
            else
                inttime = round(inttime);
                app.InttimeEditField.Value = inttime;
                app.InttimeEditFieldValueChanged;
            end

            status = calllib('bwtekusb','bwtekSetTimeUSB',inttime,0);
            
            if status ~= inttime

                uialert(app.UIFigure, ...
                    "Warning: Spec returned bad integration time.", ...
                    "Hardware Error")

            end
       
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            cd(fileparts(mfilename('fullpath')))
            addpath('lib')
            loadlibrary('bwtekusb','bwtekusb.h')

            app.Px = (0:(app.NPixels-1))';
            [app.DarkData, app.RawData] = deal(zeros(size(app.Px)));

            app.WvArray = app.Coefs.a0+ app.Coefs.a1*app.Px + ...
                app.Coefs.a2*app.Px.^2 + app.Coefs.a3*app.Px.^3;

            app.DataPtr = libpointer('uint16Ptr', zeros(app.NPixels,1));

            app.ConnectedLamp.Color = app.LampOff;
            app.DarkLamp.Color = app.LampOff;

        end

        % Button pushed function: AcquireDataButton
        function AcquireDataButtonPushed(app, event)
           
            app.Acquire;
            app.PlotData;

        end

        % Value changed function: InttimeEditField
        function InttimeEditFieldValueChanged(app, event)
            
            app.DarkLamp.Color = app.LampOff;
            app.DarkData = zeros(size(app.Px));
            app.HasDark = false;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            rmpath('lib')
            calllib('bwtekusb','bwtekCloseUSB',0);
            delete(app)

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            calllib('bwtekusb','bwtekCloseUSB',0)
            status = calllib('bwtekusb','bwtekTestUSB',6,1024,2,0,[]);
            
            if status ~= 1
                app.Initialized = false;
                app.ConnectedLamp.Color = app.LampOff;
                app.EnableAll("off");

            else
                app.Initialized = true;
                app.ConnectedLamp.Color = app.LampOn;
                app.EnableAll("on");
            end

        end

        % Button pushed function: AutoexposeButton
        function AutoexposeButtonPushed(app, event)
            
            autoX = false;
            tries = 0;
            maxsig = 0;

            while ~autoX
            
                thisIntTime = app.InttimeEditField.Value;
                app.RawData = app.Acquire;
                oldmax = maxsig;
                    maxsig = max(app.RawData);
    
                if maxsig > 10000 && maxsig < 65535
                    app.SetIntTime(min(65535, max(50, ...
                        round((63000/maxsig)*thisIntTime))));
                    app.Acquire;
                    autoX = true;
                        
                elseif maxsig <= 10000 
                    if thisIntTime < 65535

                        if tries >= 2 && maxsig/oldmax <= 2                            
                            uialert(app.UIFigure, ...
                                ["Weak signal detected."; ...
                                "Autoexpose failed."], "No Signal")
                            autoX = true;
                        else
                            app.SetIntTime(min(10*thisIntTime, 65535));
                            tries = tries+1;
                        end

                    end

                elseif maxsig >= 65000
                    if thisIntTime == 50
                        uialert(app.UIFigure, ...
                            "Saturation Detected", "Saturated");
                        autoX = true;

                    elseif maxsig == 65535
                        app.SetIntTime(max(50, round(0.3*thisIntTime)));

                    else
                        app.SetIntTime(min(65535, max(50, ...
                            round((63000/maxsig)*thisIntTime))));

                    end                                                            

                end
            end
            app.PlotData;

        end

        % Button pushed function: AcquireDarkButton
        function AcquireDarkButtonPushed(app, event)
            
            app.DarkData = app.Acquire;
            app.DarkLamp.Color = app.LampOn;
            app.HasDark = true;
            app.PlotDarkData;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            fname = fullfile("rawdata", ...
                app.OutputNameEditField.Value + ".txt");

            d = dir(fname);
            if ~isempty(d)
                selection = uiconfirm(app.UIFigure, ...
                    'Overwrite Saved Data?', 'Confirm Overwrite',...
                    'Icon','warning');
                if strcmp(selection, "OK")
                    writematrix( ...
                        [app.Px,app.WvArray,app.RawData-app.DarkData], ...
                            fname);
                    uialert(app.UIFigure, "File Saved", "", ...
                        "Icon", "Success")
                else
                    uialert(app.UIFigure, "File not saved.", "", ...
                        "Icon", "Warning")
                end
            else
                writematrix( ...
                    [app.Px,app.WvArray,app.RawData-app.DarkData], fname);
                uialert(app.UIFigure, "File Saved", "", "Icon", "Success")
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'No Data')
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Counts')
            app.UIAxes.XLim = [300 1100];
            app.UIAxes.YLim = [-10 65535];
            app.IntegrationTimemsEditFieldLabel = uilabel(app.UIFigure);
            app.IntegrationTimemsEditFieldLabel.Text = 'Integration Time (ms)';
            app.InttimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.InttimeEditField.Limits = [50 65535];
            app.InttimeEditField.RoundFractionalValues = 'on';
            app.InttimeEditField.ValueDisplayFormat = '%.8g';
            app.InttimeEditField.ValueChangedFcn = createCallbackFcn(app, @InttimeEditFieldValueChanged, true);
            app.InttimeEditField.Enable = 'off';
            app.InttimeEditField.Value = 50;
            app.AcquireDarkButton = uibutton(app.UIFigure, 'push');
            app.AcquireDarkButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireDarkButtonPushed, true);
            app.AcquireDarkButton.Enable = 'off';
            app.AcquireDarkButton.Text = 'Acquire Dark';
            app.AutoexposeButton = uibutton(app.UIFigure, 'push');
            app.AutoexposeButton.ButtonPushedFcn = createCallbackFcn(app, @AutoexposeButtonPushed, true);
            app.AutoexposeButton.Enable = 'off';
            app.AutoexposeButton.Text = 'Autoexpose';
            app.AcquireDataButton = uibutton(app.UIFigure, 'push');
            app.AcquireDataButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireDataButtonPushed, true);
            app.AcquireDataButton.Enable = 'off';
            app.AcquireDataButton.Text = 'Acquire Data';
            app.OutputNameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputNameEditFieldLabel.Text = 'Output Name';
            app.OutputNameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputNameEditField.Enable = 'off';
            app.OutputNameEditField.Value = 'Intralipid_T';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = {'CONNECT'; 'TO'; 'BWTEK'};
            app.ConnectedLamp = uilamp(app.UIFigure);
            app.DarkLamp = uilamp(app.UIFigure);
            app.SaturatedLampLabel = uilabel(app.UIFigure);
            app.SaturatedLampLabel.Text = 'Saturated';
            app.SaturatedLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IAD_acquire

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end